classdef Task < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DisplaymessageTextAreaLabel  matlab.ui.control.Label
        DisplaymessageTextArea       matlab.ui.control.TextArea
        UIAxes                       matlab.ui.control.UIAxes
        EncryptLabel                 matlab.ui.control.Label
        LoadImagetoEncryptButton     matlab.ui.control.Button
        InputMessageTextAreaLabel    matlab.ui.control.Label
        InputMessageTextArea         matlab.ui.control.TextArea
        PasswordtypeDropDownLabel    matlab.ui.control.Label
        PasswordtypeDropDown         matlab.ui.control.DropDown
        InputPasswordTextAreaLabel   matlab.ui.control.Label
        InputPasswordTextArea        matlab.ui.control.TextArea
        EncryptButton                matlab.ui.control.Button
        ClearallButton               matlab.ui.control.Button
        DecryptLabel                 matlab.ui.control.Label
        LoadImagetoDecryptButton     matlab.ui.control.Button
        PasswordtypeDropDown_2Label  matlab.ui.control.Label
        PasswordtypeDropDown_2       matlab.ui.control.DropDown
        PasswordTextAreaLabel        matlab.ui.control.Label
        PasswordTextArea             matlab.ui.control.TextArea
        DecryptButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        inputmessage;
        inputpassword;
        inputmessagearray;
        inputimage;
        outputimage;
        encryptedimage;
        outputpassword;
        decryptedimage;
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
        end

        % Value changed function: InputMessageTextArea
        function InputMessageTextAreaValueChanged(app, event)
            value = app.InputMessageTextArea.Value;
            value = char(string(value))
            app.inputmessage = value;
            app.inputmessagearray = todecimal(app.inputmessage);
            
        end

        % Value changed function: InputPasswordTextArea
        function InputPasswordTextAreaValueChanged(app, event)
            value = app.InputPasswordTextArea.Value;
            app.inputpassword = char(string(value));
        end

        % Button pushed function: LoadImagetoEncryptButton
        function LoadImagetoEncryptButtonPushed(app, event)
            name = uigetfile('*jpg');
            im = imread(name);
            app.inputimage = im;
            imshow(im, 'parent', app.UIAxes);
            app.UIAxes.Title.String = 'Input Image'
        end

        % Value changed function: PasswordtypeDropDown
        function PasswordtypeDropDownValueChanged(app, event)
            value = app.PasswordtypeDropDown.Value;
            switch value
                case 'Text'
                    app.InputPasswordTextArea.Enable = 'on'
                case 'sound signal'
                    file = uigetfile({'*.wav';'*.flac';'*.mp3'}, 'Select file');
                    app.inputpassword = file;
                    app.InputPasswordTextArea.Enable = 'off'
            end
        
        end

        % Button pushed function: EncryptButton
        function EncryptButtonPushed(app, event)
            L = length(app.inputmessagearray);
            k = 1;
            [row,col] = size(app.inputimage)
            encryptedimage = app.inputimage;
            for i = 1:row
                for j = 1:col
                                        
                    if k<=L
                       encryptedimage(i,j) = app.inputmessagearray(k); 
                    end
                    k = k+1;
                end
            end
            imwrite(encryptedimage, 'encryptedimage.png');
            imshow(encryptedimage, 'parent', app.UIAxes);
            app.UIAxes.Title.String = 'Image contain with message'
                  
               
        end

        % Value changed function: PasswordTextArea
        function PasswordTextAreaValueChanged(app, event)
            value = app.PasswordTextArea.Value;
            app.outputpassword = char(string(value));
            
        end

        % Button pushed function: DecryptButton
        function DecryptButtonPushed(app, event)
            if ~ all(app.inputpassword == app.outputpassword)
                errordlg('Password did not match')
            else
            im = double(app.outputimage);
            value = im(im ~= 255);
            message = char(value');
            app.DisplaymessageTextArea.Value = message;
            end


        end

        % Button pushed function: LoadImagetoDecryptButton
        function LoadImagetoDecryptButtonPushed(app, event)
            name = uigetfile('*png');
            im = imread(name);
            app.outputimage = im;
            imshow(im, 'parent', app.UIAxes);
        end

        % Value changed function: PasswordtypeDropDown_2
        function PasswordtypeDropDown_2ValueChanged(app, event)
            value = app.PasswordtypeDropDown_2.Value;
            switch value
                case 'Text'
                    app.PasswordTextArea.Enable = 'on'
                case 'sound signal'
                    file = uigetfile({'*.wav';'*.flac';'*.mp3'}, 'Select file');
                    app.outputpassword = file;
                    app.PasswordTextArea.Enable = 'off'
            end
        
        end

        % Button pushed function: ClearallButton
        function ClearallButtonPushed(app, event)
            cla(app.UIAxes);
            app.InputMessageTextArea.Value = '';
            app.DisplaymessageTextArea.Value = '';
            app.InputPasswordTextArea.Value = '';
            app.PasswordTextArea.Value = '';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.DisplaymessageTextAreaLabel = uilabel(app.UIFigure);
            app.DisplaymessageTextAreaLabel.Text = 'Display message';
            app.DisplaymessageTextArea = uitextarea(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.EncryptLabel = uilabel(app.UIFigure);
            app.EncryptLabel.Text = 'Encrypt';
            app.LoadImagetoEncryptButton = uibutton(app.UIFigure, 'push');
            app.LoadImagetoEncryptButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImagetoEncryptButtonPushed, true);
            app.LoadImagetoEncryptButton.Text = 'Load Image to Encrypt';
            app.InputMessageTextAreaLabel = uilabel(app.UIFigure);
            app.InputMessageTextAreaLabel.Text = 'Input Message';
            app.InputMessageTextArea = uitextarea(app.UIFigure);
            app.InputMessageTextArea.ValueChangedFcn = createCallbackFcn(app, @InputMessageTextAreaValueChanged, true);
            app.PasswordtypeDropDownLabel = uilabel(app.UIFigure);
            app.PasswordtypeDropDownLabel.Text = 'Password type';
            app.PasswordtypeDropDown = uidropdown(app.UIFigure);
            app.PasswordtypeDropDown.Items = {'Text', 'sound signal', '', ''};
            app.PasswordtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PasswordtypeDropDownValueChanged, true);
            app.PasswordtypeDropDown.Value = 'Text';
            app.InputPasswordTextAreaLabel = uilabel(app.UIFigure);
            app.InputPasswordTextAreaLabel.Text = 'Input Password';
            app.InputPasswordTextArea = uitextarea(app.UIFigure);
            app.InputPasswordTextArea.ValueChangedFcn = createCallbackFcn(app, @InputPasswordTextAreaValueChanged, true);
            app.EncryptButton = uibutton(app.UIFigure, 'push');
            app.EncryptButton.ButtonPushedFcn = createCallbackFcn(app, @EncryptButtonPushed, true);
            app.EncryptButton.Text = 'Encrypt';
            app.ClearallButton = uibutton(app.UIFigure, 'push');
            app.ClearallButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallButtonPushed, true);
            app.ClearallButton.Text = 'Clear all';
            app.DecryptLabel = uilabel(app.UIFigure);
            app.DecryptLabel.Text = 'Decrypt';
            app.LoadImagetoDecryptButton = uibutton(app.UIFigure, 'push');
            app.LoadImagetoDecryptButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImagetoDecryptButtonPushed, true);
            app.LoadImagetoDecryptButton.Text = 'Load Image to Decrypt';
            app.PasswordtypeDropDown_2Label = uilabel(app.UIFigure);
            app.PasswordtypeDropDown_2Label.Text = 'Password type';
            app.PasswordtypeDropDown_2 = uidropdown(app.UIFigure);
            app.PasswordtypeDropDown_2.Items = {'Text', 'sound signal', '', ''};
            app.PasswordtypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @PasswordtypeDropDown_2ValueChanged, true);
            app.PasswordtypeDropDown_2.Value = 'Text';
            app.PasswordTextAreaLabel = uilabel(app.UIFigure);
            app.PasswordTextAreaLabel.Text = 'Password';
            app.PasswordTextArea = uitextarea(app.UIFigure);
            app.PasswordTextArea.ValueChangedFcn = createCallbackFcn(app, @PasswordTextAreaValueChanged, true);
            app.DecryptButton = uibutton(app.UIFigure, 'push');
            app.DecryptButton.ButtonPushedFcn = createCallbackFcn(app, @DecryptButtonPushed, true);
            app.DecryptButton.Text = 'Decrypt';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Task

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end